classdef voice_analyzer_app_NI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VoiceAnalyzerUIFigure  matlab.ui.Figure
        P3                     matlab.ui.control.UIAxes
        P4                     matlab.ui.control.UIAxes
        Button                 matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        dBLabel_2              matlab.ui.control.Label
        result1                matlab.ui.control.NumericEditField
        dBLabel                matlab.ui.control.Label
        result2                matlab.ui.control.NumericEditField
        Panel_2                matlab.ui.container.Panel
        HzLabel                matlab.ui.control.Label
        input1                 matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        input2                 matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        input3                 matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        input4                 matlab.ui.control.NumericEditField
        Label_5                matlab.ui.control.Label
        input5                 matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Callback function: Button, result1, result2
        function ButtonPushed(app, event)
            Fs = app.input1.Value;          % 采样率 (例如：10000 Hz)
             nBits = app.input2.Value;          % 位数 (例如：16位)
             nChannels = app.input3.Value;       % 声道数 (例如：1表示单声道)
             deviceID = app.input4.Value;        % 设备ID (例如：1表示默认输入设备)
            duration =app.input5.Value;      % 录音持续时间（秒）
            
             %NI麦克风采集
             AI = daq.createSession('ni');

             % 在此只用单个麦克风单个通道，如需多个使用注释模板
             addAnalogInputChannel(AI,'Dev1',0,'Voltage'); %AI+采集卡序列+通道序列
             % addAnalogInputChannel(AI,'Dev1',0:12,'Microphone'); 

             ch1                 = AI.Channels(1);

             % 单个麦克风灵敏度校正
             Sensitivity_ch1     = 54.1/1000;

             AI.Rate = Fs;
             AI.DurationInSeconds = duration;
             AI;
             data = AI.startForeground();
             stop(AI);
             delete(AI);
             mic=data;
             mic = mic * Sensitivity_ch1;   
             pressure = mic;
            
            %功率谱
             ref_pressure = 20e-6; % 参考压力 (Pa)
             [V,fx]=pwelch(mic, [],[],[], Fs,'power');
             power=10*log10(V.^2)/ref_pressure/100000;
             plot(app.P4,fx,power);
          
             % 使用汉宁窗函数
             window = hann(length(mic));
             pressure_windowed = mic .* window;
             fft_pressure = fft(pressure_windowed);
             % 计算声压级频谱
             L=length(mic);
             ref_pressure = 20e-6; % 参考压力 (Pa)
             SPL = 20 * log10(abs(fft_pressure(1:L/2 + 1)) / ref_pressure);
             % 计算总声压级OASPL
             f_min = 20; % 最小频率
             f_max = 20000; % 最大频率
             f_range = f_max - f_min; % 频率范围
             f_idx_min = round(f_min / L * (L/2)); % 最小频率下标
             f_idx_max = L/2; % 最大频率下标
             OASPL = 10 * log10(sum(10.^(SPL(f_idx_min:f_idx_max)/10))/f_range);
             n_f = length(SPL);
             f=[1:n_f]/10;
            % 绘制声压级随频率变化的图形
             plot(app.P3,f,SPL);
            
             app.result1.Value=mean(SPL(1:10000));
             app.result2.Value=mean(power(1:5000));
        end

        % Callback function
        function dBEditFieldValueChanged(app, event)
    
        end

        % Callback function
        function dBEditField_2ValueChanged(app, event)
  
        end

        % Callback function
        function dBTextAreaValueChanged(app, event)
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VoiceAnalyzerUIFigure = uifigure;
            app.VoiceAnalyzerUIFigure.Name = 'Voice Analyzer';
            app.P3 = uiaxes(app.VoiceAnalyzerUIFigure);
            title(app.P3, '声压级变化曲线')
            xlabel(app.P3, '频率/Hz')
            ylabel(app.P3, '声压级/dB')
            app.P4 = uiaxes(app.VoiceAnalyzerUIFigure);
            title(app.P4, 'Welch功率谱')
            xlabel(app.P4, '频率/Hz')
            ylabel(app.P4, '功率级/dB')
            app.Button = uibutton(app.VoiceAnalyzerUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始测量';
            app.Panel = uipanel(app.VoiceAnalyzerUIFigure);
            app.Panel.Title = '输出结果';
            app.dBLabel_2 = uilabel(app.Panel);
            app.dBLabel_2.Text = '稳定声压级/dB';
            app.result1 = uieditfield(app.Panel, 'numeric');
            app.result1.ValueDisplayFormat = '%.3f';
            app.result1.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.dBLabel = uilabel(app.Panel);
            app.dBLabel.Text = '稳定功率级/dB';
            app.result2 = uieditfield(app.Panel, 'numeric');
            app.result2.ValueDisplayFormat = '%.3f';
            app.result2.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Panel_2 = uipanel(app.VoiceAnalyzerUIFigure);
            app.Panel_2.Title = '采样参数';
            app.HzLabel = uilabel(app.Panel_2);
            app.HzLabel.Text = '采样频率/Hz';
            app.input1 = uieditfield(app.Panel_2, 'numeric');
            app.input1.ValueDisplayFormat = '%.0f';
            app.input1.Value = 50000;
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '采样位数';
            app.input2 = uieditfield(app.Panel_2, 'numeric');
            app.input2.ValueDisplayFormat = '%.0f';
            app.input2.Value = 16;
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '采样声道数';
            app.input3 = uieditfield(app.Panel_2, 'numeric');
            app.input3.ValueDisplayFormat = '%.0f';
            app.input3.Value = 1;
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '采样设备序号';
            app.input4 = uieditfield(app.Panel_2, 'numeric');
            app.input4.ValueDisplayFormat = '%.0f';
            app.input4.Value = 1;
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '采样总时间';
            app.input5 = uieditfield(app.Panel_2, 'numeric');
            app.input5.ValueDisplayFormat = '%.0f';
            app.input5.Value = 15;
        end
    end

    methods (Access = public)

        % Construct app
        function app = voice_analyzer_app_NI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VoiceAnalyzerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VoiceAnalyzerUIFigure)
        end
    end
end